classdef WolfeLA3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        InputPanel                     matlab.ui.container.Panel
        ToggleY1SolutionsPanel         matlab.ui.container.Panel
        ODE45CheckBox1                 matlab.ui.control.CheckBox
        EulersMethodCheckBox1          matlab.ui.control.CheckBox
        AnalyticalActualCheckBox1      matlab.ui.control.CheckBox
        BackwardsEulerMethodCheckBox1  matlab.ui.control.CheckBox
        MidpointEulerMethodCheckBox1   matlab.ui.control.CheckBox
        ToggleY2SolutionsPanel         matlab.ui.container.Panel
        ODE45CheckBox2                 matlab.ui.control.CheckBox
        EulersMethodCheckBox2          matlab.ui.control.CheckBox
        AnalyticalActualCheckBox2      matlab.ui.control.CheckBox
        BackwardsEulerMethodCheckBox2  matlab.ui.control.CheckBox
        MidpointEulerMethodCheckBox2   matlab.ui.control.CheckBox
        ParametersforGraphsPanel       matlab.ui.container.Panel
        StepsEditFieldLabel            matlab.ui.control.Label
        StepsEditField                 matlab.ui.control.NumericEditField
        XValueEditFieldLabel           matlab.ui.control.Label
        XValueEditField                matlab.ui.control.NumericEditField
        AccuracyatFinalXValuePanel     matlab.ui.container.Panel
        EquationButtonGroup            matlab.ui.container.ButtonGroup
        Equation1Button                matlab.ui.control.RadioButton
        Equation2Button                matlab.ui.control.RadioButton
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        EulersMethodButton             matlab.ui.control.RadioButton
        ODE45Button                    matlab.ui.control.RadioButton
        BackwardsEulerButton           matlab.ui.control.RadioButton
        MidpointEulerButton            matlab.ui.control.RadioButton
        PercentErrorLabel              matlab.ui.control.Label
        PercentErrorValue              matlab.ui.control.Label
        UpdatePlotsButton              matlab.ui.control.Button
        GraphPanel                     matlab.ui.container.Panel
        Y2Graph                        matlab.ui.control.UIAxes
        Y1Graph                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Equations
        % Differential Equations
        y1prime = @ (x, y) 2 - exp(-4*x) - 2*y;
        y2prime = @ (x, y) y - 1/2 * exp(x/2) .* sin(5*x) + 5 * exp(x/2) .* cos(5*x);
        
        % Analytical Solutions
        y1Sol = @(x) 1 + (exp(-4*x)-exp(-2*x))/2;
        y2Sol = @(x) exp(x/2) .* sin(5*x);
        
        % Initial Conditions
        y1o = 1;
        y2o = 0;
        
        % Default values
        steps = 100;
        finalX = 1;
        
        % Graph Colors
        red = [231, 76, 60]/255;
        orange = [230, 126, 34]/255;
        blue = [52, 152, 219]/255;
        purple = [155, 89, 182]/255;
        green = [46, 204, 113]/255;
        
        % X arrays declaration
        X = zeros(100);
        analyticalX = zeros(100);

        % Euler's Method property declaration
        y1Euler = zeros(100);
        y2Euler = zeros(100);
    
        % Analytical property declaration
        y1Analytical = zeros(100);
        y2Analytical = zeros(100);

        % Midpoint Euler Method property declaration
        y1Midpoint = zeros(100);
        y2Midpoint = zeros(100);
        
        % Backward Euler Method property declaration
        y1Backward = zeros(100);
        y2Backward = zeros(100);
        
        % ode45 property declaration
        ode45X1 = zeros(100);
        ode45X2 = zeros(100);
        ode45Y1 = zeros(100);
        ode45Y2 = zeros(100);
        
        % stepSize property declaration
        stepSize = .01;

    end
    
    methods (Access = private)
        
        % This function is called at startup, and whenever the Generate
        % Plots button is pressed.  This function updates the plots, and
        % the percent error calculations based on the user's selection of
        % graphs to show, Final X value, and number of steps.
        function setupApp(app)
                    
            % Initial variables setup
            app.steps = app.StepsEditField.Value;
            app.finalX = app.XValueEditField.Value;
            app.stepSize = app.finalX/app.steps;
            
            % Initial setup of arrays
            app.X = linspace(0, app.finalX, app.steps + 1);
            app.analyticalX = linspace(0, app.finalX, app.finalX * 100 + 1);
            app.y1Euler = linspace(app.y1o, app.y1o, app.steps + 1);
            app.y2Euler = linspace(app.y2o, app.y2o, app.steps + 1);
            app.y1Analytical = app.y1Sol(app.analyticalX);
            app.y2Analytical = app.y2Sol(app.analyticalX);
            app.y1Midpoint = linspace(app.y1o, app.y1o, app.steps + 1);
            app.y2Midpoint = linspace(app.y2o, app.y2o, app.steps + 1);
            app.y1Backward = linspace(app.y1o, app.y1o, app.steps + 1);
            app.y2Backward = linspace(app.y2o, app.y2o, app.steps + 1);
            
            % For repeated calculations (Euler's Method, Midpoint Method, etc...)
            for i = 2:app.steps+1
                % Perform Euler's Method calculation of points in X
                [app.y1Euler(i), app.y2Euler(i)] = calculateYValuesEuler(app.X(i-1), app.y1Euler(i-1), app.y2Euler(i-1), app.stepSize);
     
                % Perform Backward Euler Method calculation of points in X.
                [app.y1Backward(i), app.y2Backward(i)] = calculateYValuesBackwardEuler(app.X(i-1), app.y1Backward(i-1), app.y2Backward(i-1), app.stepSize);
    
                % Perform Midpoint Euler Method calculation of points in X.
                [app.y1Midpoint(i), app.y2Midpoint(i)] = calculateYValuesMidpointEuler(app.X(i-1), app.y1Midpoint(i-1), app.y2Midpoint(i-1), app.stepSize);
            end
            
            % Use ode45 for both ODE's
            [app.ode45X1, app.ode45Y1] = ode45(app.y1prime, [0 app.finalX], app.y1o);
            [app.ode45X2, app.ode45Y2] = ode45(app.y2prime, [0 app.finalX], app.y2o);
            
            updatePercentError(app);
            
            % Clear graphs
            app.Y1Graph.cla;
            app.Y2Graph.cla;
            
            % Plot Y1 (Upper Graph)
            hold(app.Y1Graph, "on");
            
            % The following if statements make sure that the check box is
            % selected, and if so, the function will be plotted.
            
            % Euler's Method
            if app.EulersMethodCheckBox1.Value == 1
                plot(app.Y1Graph, app.X, app.y1Euler, "Color", app.red, "DisplayName", "Euler's Method");
            end
            
            % Analytical Solution
            if app.AnalyticalActualCheckBox1.Value == 1
                plot(app.Y1Graph, app.analyticalX, app.y1Analytical, "Color", app.orange, "DisplayName", "Analytical (Actual) Solution");
            end
            
            % Ode 45
            if app.ODE45CheckBox1.Value == 1
                plot(app.Y1Graph, app.ode45X1, app.ode45Y1, "Color", app.blue, "DisplayName", "Matlab ODE45");
            end
            
            % Backwards Euler Method
            if app.BackwardsEulerMethodCheckBox1.Value == 1
                plot(app.Y1Graph, app.X, app.y1Backward, "Color", app.green, "DisplayName", "Backward Euler Method");
            end
            
            % Midpoint Euler Method
            if app.MidpointEulerMethodCheckBox1.Value == 1
                plot(app.Y1Graph, app.X, app.y1Midpoint, "Color", app.purple, "DisplayName", "Midpoint Euler Method");
            end
            
            % Add legend
            legend(app.Y1Graph, "Location", "Best");
            
            % Turn on grid
            grid(app.Y1Graph, "on");
            
            hold(app.Y1Graph, "off");
            
            % Plot Y2 (Lower Graph)
            hold(app.Y2Graph, "on");
            
            % Euler's Method
            if app.EulersMethodCheckBox2.Value == 1
                plot(app.Y2Graph, app.X, app.y2Euler, "Color", app.red, "DisplayName", "Euler's Method");
            end
            
            % Analytical Solution
            if app.AnalyticalActualCheckBox2.Value == 1
                plot(app.Y2Graph, app.analyticalX, app.y2Analytical, "Color", app.orange, "DisplayName", "Analytical (Actual) Solution");
            end
            
            % Ode45
            if app.ODE45CheckBox2.Value == 1
                plot(app.Y2Graph, app.ode45X2, app.ode45Y2, "Color", app.blue, "DisplayName", "Matlab ODE45");
            end
            
            % Backwards Euler Method
            if app.BackwardsEulerMethodCheckBox2.Value == 1
                plot(app.Y2Graph, app.X, app.y2Backward, "Color", app.green, "DisplayName", "Backward Euler Method");
            end
            
            % Midpoint Euler Method
            if app.MidpointEulerMethodCheckBox2.Value == 1
                plot(app.Y2Graph, app.X, app.y2Midpoint, "Color", app.purple, "DisplayName", "Midpoint Euler Method");
            end
            
            % Add legend to graph
            legend(app.Y2Graph, "Location", "Best");
            
            % Add Grid to graph
            grid(app.Y2Graph, "on");
            
            hold(app.Y2Graph, "off");
            
        end
        
        % This function calculates the percent error of the selected
        % equation and estimation method.  This function is called at
        % startup, when the Generate Plots button is pressed, and when the
        % user selects a different equation or estimation method.
        function updatePercentError(app)
            
            % Get the selected radio buttons
            equationButton = app.EquationButtonGroup.SelectedObject;
            methodButton = app.MethodButtonGroup.SelectedObject;
            
            % Which equation is selected? (1 or 2)
            if equationButton == app.Equation1Button
                equationState = 0;
                realValue = app.y1Sol(app.XValueEditField.Value);
            else
                equationState = 1;
                realValue = app.y2Sol(app.XValueEditField.Value);
            end
            
            % Which approximation method is selected?
            % Euler's Method
            if methodButton == app.EulersMethodButton
                if equationState == 0
                    estimateValue = app.y1Euler(end);
                else
                    estimateValue = app.y2Euler(end);
                end
            
            % Ode45
            elseif methodButton == app.ODE45Button
                if equationState == 0
                    estimateValue = app.ode45Y1(end);
                else
                    estimateValue = app.ode45Y2(end);
                end
                
            % Backwards Euler Method
            elseif methodButton == app.BackwardsEulerButton
                if equationState == 0
                    estimateValue = app.y1Backward(end);
                else
                    estimateValue = app.y2Backward(end);
                end
                
            % Midpoint Euler Method
            else
                if equationState == 0
                    estimateValue = app.y1Midpoint(end);
                else
                    estimateValue = app.y2Midpoint(end);
                end
            end
            
            % Calculate percent error and update percent error value in app
            app.PercentErrorValue.Text = sprintf('%.4f%%', 100*abs((realValue-estimateValue)/realValue));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setupApp(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.GraphPanel.Layout.Row = 2;
                app.GraphPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.GraphPanel.Layout.Row = 1;
                app.GraphPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: UpdatePlotsButton
        function UpdatePlotsButtonPushed(app, event)
            setupApp(app);
        end

        % Selection changed function: EquationButtonGroup
        function EquationButtonGroupSelectionChanged(app, event)
            updatePercentError(app);
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            updatePercentError(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Display Options';
            app.ToggleY1SolutionsPanel = uipanel(app.InputPanel);
            app.ToggleY1SolutionsPanel.Title = 'Toggle Y1 Solutions';
            app.ODE45CheckBox1 = uicheckbox(app.ToggleY1SolutionsPanel);
            app.ODE45CheckBox1.ValueChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ODE45CheckBox1.Text = 'Matlab ode45';
            app.EulersMethodCheckBox1 = uicheckbox(app.ToggleY1SolutionsPanel);
            app.EulersMethodCheckBox1.Text = 'Euler''s Method';
            app.EulersMethodCheckBox1.Value = true;
            app.AnalyticalActualCheckBox1 = uicheckbox(app.ToggleY1SolutionsPanel);
            app.AnalyticalActualCheckBox1.Text = 'Analytical (Actual)';
            app.AnalyticalActualCheckBox1.Value = true;
            app.BackwardsEulerMethodCheckBox1 = uicheckbox(app.ToggleY1SolutionsPanel);
            app.BackwardsEulerMethodCheckBox1.Text = 'Backwards Euler Method';
            app.MidpointEulerMethodCheckBox1 = uicheckbox(app.ToggleY1SolutionsPanel);
            app.MidpointEulerMethodCheckBox1.Text = 'Midpoint Euler Method';
            app.ToggleY2SolutionsPanel = uipanel(app.InputPanel);
            app.ToggleY2SolutionsPanel.Title = 'Toggle Y2 Solutions';
            app.ODE45CheckBox2 = uicheckbox(app.ToggleY2SolutionsPanel);
            app.ODE45CheckBox2.Text = 'Matlab ode45';
            app.EulersMethodCheckBox2 = uicheckbox(app.ToggleY2SolutionsPanel);
            app.EulersMethodCheckBox2.Text = 'Euler''s Method';
            app.EulersMethodCheckBox2.Value = true;
            app.AnalyticalActualCheckBox2 = uicheckbox(app.ToggleY2SolutionsPanel);
            app.AnalyticalActualCheckBox2.Text = 'Analytical (Actual)';
            app.AnalyticalActualCheckBox2.Value = true;
            app.BackwardsEulerMethodCheckBox2 = uicheckbox(app.ToggleY2SolutionsPanel);
            app.BackwardsEulerMethodCheckBox2.Text = 'Backwards Euler Method';
            app.MidpointEulerMethodCheckBox2 = uicheckbox(app.ToggleY2SolutionsPanel);
            app.MidpointEulerMethodCheckBox2.Text = 'Midpoint Euler Method';
            app.ParametersforGraphsPanel = uipanel(app.InputPanel);
            app.ParametersforGraphsPanel.Title = 'Parameters for Graphs';
            app.StepsEditFieldLabel = uilabel(app.ParametersforGraphsPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.ParametersforGraphsPanel, 'numeric');
            app.StepsEditField.Limits = [0 1000000];
            app.StepsEditField.Value = 100;
            app.XValueEditFieldLabel = uilabel(app.ParametersforGraphsPanel);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XValueEditField = uieditfield(app.ParametersforGraphsPanel, 'numeric');
            app.XValueEditField.Limits = [0 100];
            app.XValueEditField.Value = 1;
            app.AccuracyatFinalXValuePanel = uipanel(app.InputPanel);
            app.AccuracyatFinalXValuePanel.Title = 'Accuracy at Final X Value';
            app.EquationButtonGroup = uibuttongroup(app.AccuracyatFinalXValuePanel);
            app.EquationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EquationButtonGroupSelectionChanged, true);
            app.Equation1Button = uiradiobutton(app.EquationButtonGroup);
            app.Equation1Button.Text = 'Equation 1';
            app.Equation1Button.Value = true;
            app.Equation2Button = uiradiobutton(app.EquationButtonGroup);
            app.Equation2Button.Text = 'Equation 2';
            app.MethodButtonGroup = uibuttongroup(app.AccuracyatFinalXValuePanel);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.EulersMethodButton = uiradiobutton(app.MethodButtonGroup);
            app.EulersMethodButton.Text = 'Euler''s Method';
            app.EulersMethodButton.Value = true;
            app.ODE45Button = uiradiobutton(app.MethodButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.BackwardsEulerButton = uiradiobutton(app.MethodButtonGroup);
            app.BackwardsEulerButton.Text = 'Backwards Euler';
            app.MidpointEulerButton = uiradiobutton(app.MethodButtonGroup);
            app.MidpointEulerButton.Text = 'Midpoint Euler';
            app.PercentErrorLabel = uilabel(app.AccuracyatFinalXValuePanel);
            app.PercentErrorLabel.Text = {'Percent'; 'Error'};
            app.PercentErrorValue = uilabel(app.AccuracyatFinalXValuePanel);
            app.PercentErrorValue.Text = '0%';
            app.UpdatePlotsButton = uibutton(app.AccuracyatFinalXValuePanel, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Generate Plots';
            app.GraphPanel = uipanel(app.GridLayout);
            app.GraphPanel.Title = 'Graphs of Solutions to ODE''s';
            app.Y2Graph = uiaxes(app.GraphPanel);
            title(app.Y2Graph, 'y^{\prime} = y + e^{x/2}(5cos(5x) - sin(5x)/2)')
            xlabel(app.Y2Graph, 'x')
            ylabel(app.Y2Graph, 'y')
            app.Y1Graph = uiaxes(app.GraphPanel);
            title(app.Y1Graph, 'y^{\prime} = 2 - e^{-4x} - 2y')
            xlabel(app.Y1Graph, 'x')
            ylabel(app.Y1Graph, 'y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WolfeLA3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end